classdef GUI_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        XEditFieldLabel      matlab.ui.control.Label
        XPos                 matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        YPos                 matlab.ui.control.NumericEditField
        ZEditFieldLabel      matlab.ui.control.Label
        ZPos                 matlab.ui.control.NumericEditField
        AlphaEditFieldLabel  matlab.ui.control.Label
        Alpha                matlab.ui.control.NumericEditField
        BitaEditFieldLabel   matlab.ui.control.Label
        Bita                 matlab.ui.control.NumericEditField
        GamaEditFieldLabel   matlab.ui.control.Label
        Gama                 matlab.ui.control.NumericEditField
        Inverse_Kinematics   matlab.ui.control.Button
        Th1EditFieldLabel    matlab.ui.control.Label
        Th1Angle             matlab.ui.control.NumericEditField
        Th2EditFieldLabel    matlab.ui.control.Label
        Th2Angle             matlab.ui.control.NumericEditField
        Th3EditFieldLabel    matlab.ui.control.Label
        Th3Angle             matlab.ui.control.NumericEditField
        Th4EditFieldLabel    matlab.ui.control.Label
        Th4Angle             matlab.ui.control.NumericEditField
        Th5EditFieldLabel    matlab.ui.control.Label
        Th5Angle             matlab.ui.control.NumericEditField
        Th6EditFieldLabel    matlab.ui.control.Label
        Th6Angle             matlab.ui.control.NumericEditField
        DOFROBOTICArmLabel   matlab.ui.control.Label
        ArduinoPortPanel     matlab.ui.container.Panel
        connectButton        matlab.ui.control.Button
        diconnectButton      matlab.ui.control.Button
        Lamp                 matlab.ui.control.Lamp
    end


    properties (Access =  public)
    end

    properties (Access = private)
       
           % define colors
        GREEN_COLOR = [0 1 0];
        RED_COLOR = [1 0 0];
      
    end

    methods (Access = public)
    
        
    end

    methods (Access = private)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   %insure serial not busy
%            delete(instrfind({'Port'},{'com4'}));
%            global s;
%            s=serial('com4','BAUD', 9600);
%            fopen(s);
%            pause(2);
%                 app.Lamp.Color = app.GREEN_COLOR; 


        end

        % Button pushed function: Inverse_Kinematics
        function Inverse_KinematicsButtonPushed(app, event)
            U(1)= app.XPos.Value;
            U(2)= app.YPos.Value;
            U(3)= app.ZPos.Value;
            U(4)= app.Alpha.Value;
            U(5)= app.Bita.Value;
            U(6)= app.Gama.Value;
           [Y]=inverse_kinematics(U)    ;   
           
           app.Th1Angle.Value=Y(1);
           app.Th2Angle.Value=Y(2);
           app.Th3Angle.Value=Y(3);
           app.Th4Angle.Value=Y(4);
           app.Th5Angle.Value=Y(5);
           app.Th6Angle.Value=Y(6);
          global s;
%           fprintf(s, '%f %f %f %f %f %f \n', [Y(1),Y(2),Y(3),Y(4),Y(5),Y(6)]);
           for i=1:1:6             
           fprintf(s, '%f  \n', [Y(i)]);
            end
         %  fprintf(app.s, '%d \n', Y(1));  
          
                
        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            delete(instrfind({'Port'},{'com4'}));
           global s;
           s=serial('com4','BAUD', 115200);
           fopen(s);
           pause(2);  
              app.Lamp.Color = app.GREEN_COLOR;
        end

        % Button pushed function: diconnectButton
        function diconnectButtonPushed(app, event)
              global s;
            fclose(s);
            clear s; %Remove the variable
            app.Lamp.Color = app.RED_COLOR;
               
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XPos = uieditfield(app.UIFigure, 'numeric');
            app.XPos.Value = 69;
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YPos = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZPos = uieditfield(app.UIFigure, 'numeric');
            app.ZPos.Value = -135.5;
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.Alpha = uieditfield(app.UIFigure, 'numeric');
            app.BitaEditFieldLabel = uilabel(app.UIFigure);
            app.BitaEditFieldLabel.Text = 'Bita';
            app.Bita = uieditfield(app.UIFigure, 'numeric');
            app.Bita.Value = 90;
            app.GamaEditFieldLabel = uilabel(app.UIFigure);
            app.GamaEditFieldLabel.Text = 'Gama';
            app.Gama = uieditfield(app.UIFigure, 'numeric');
            app.Gama.Value = 180;
            app.Inverse_Kinematics = uibutton(app.UIFigure, 'push');
            app.Inverse_Kinematics.ButtonPushedFcn = createCallbackFcn(app, @Inverse_KinematicsButtonPushed, true);
            app.Inverse_Kinematics.Text = 'Inverse_Kinematics';
            app.Th1EditFieldLabel = uilabel(app.UIFigure);
            app.Th1EditFieldLabel.Text = 'Th1';
            app.Th1Angle = uieditfield(app.UIFigure, 'numeric');
            app.Th1Angle.Editable = 'off';
            app.Th2EditFieldLabel = uilabel(app.UIFigure);
            app.Th2EditFieldLabel.Text = 'Th2';
            app.Th2Angle = uieditfield(app.UIFigure, 'numeric');
            app.Th2Angle.Editable = 'off';
            app.Th3EditFieldLabel = uilabel(app.UIFigure);
            app.Th3EditFieldLabel.Text = 'Th3';
            app.Th3Angle = uieditfield(app.UIFigure, 'numeric');
            app.Th3Angle.Editable = 'off';
            app.Th4EditFieldLabel = uilabel(app.UIFigure);
            app.Th4EditFieldLabel.Text = 'Th4';
            app.Th4Angle = uieditfield(app.UIFigure, 'numeric');
            app.Th4Angle.Editable = 'off';
            app.Th5EditFieldLabel = uilabel(app.UIFigure);
            app.Th5EditFieldLabel.Text = 'Th5';
            app.Th5Angle = uieditfield(app.UIFigure, 'numeric');
            app.Th5Angle.Editable = 'off';
            app.Th6EditFieldLabel = uilabel(app.UIFigure);
            app.Th6EditFieldLabel.Text = 'Th6';
            app.Th6Angle = uieditfield(app.UIFigure, 'numeric');
            app.Th6Angle.Editable = 'off';
            app.DOFROBOTICArmLabel = uilabel(app.UIFigure);
            app.DOFROBOTICArmLabel.Text = '6DOF  ROBOTIC Arm';
            app.ArduinoPortPanel = uipanel(app.UIFigure);
            app.ArduinoPortPanel.Title = 'Arduino Port';
            app.connectButton = uibutton(app.ArduinoPortPanel, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.diconnectButton = uibutton(app.ArduinoPortPanel, 'push');
            app.diconnectButton.ButtonPushedFcn = createCallbackFcn(app, @diconnectButtonPushed, true);
            app.diconnectButton.Text = 'diconnect';
            app.Lamp = uilamp(app.ArduinoPortPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_V1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end