classdef GUI_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARM_GUI                  matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab1                     matlab.ui.container.Tab
        Inverse_KinematicsPanel  matlab.ui.container.Panel
        DOFROBOTICArmLabel       matlab.ui.control.Label
        ArduinoPortPanel         matlab.ui.container.Panel
        connectButton            matlab.ui.control.Button
        diconnectButton          matlab.ui.control.Button
        Lamp                     matlab.ui.control.Lamp
        PortDropDownLabel        matlab.ui.control.Label
        PortList                 matlab.ui.control.DropDown
        RefreshButton            matlab.ui.control.Button
        Inverse_Kinematics       matlab.ui.control.Button
        XEditFieldLabel          matlab.ui.control.Label
        XPos                     matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YPos                     matlab.ui.control.NumericEditField
        ZEditFieldLabel          matlab.ui.control.Label
        ZPos                     matlab.ui.control.NumericEditField
        AlphaEditFieldLabel      matlab.ui.control.Label
        Alpha                    matlab.ui.control.NumericEditField
        BitaEditFieldLabel       matlab.ui.control.Label
        Bita                     matlab.ui.control.NumericEditField
        GamaEditFieldLabel       matlab.ui.control.Label
        Gama                     matlab.ui.control.NumericEditField
        Th1EditFieldLabel        matlab.ui.control.Label
        Th1Angle                 matlab.ui.control.NumericEditField
        Th2EditFieldLabel        matlab.ui.control.Label
        Th2Angle                 matlab.ui.control.NumericEditField
        Th3EditFieldLabel        matlab.ui.control.Label
        Th3Angle                 matlab.ui.control.NumericEditField
        Th4EditFieldLabel        matlab.ui.control.Label
        Th4Angle                 matlab.ui.control.NumericEditField
        Th5EditFieldLabel        matlab.ui.control.Label
        Th5Angle                 matlab.ui.control.NumericEditField
        Th6EditFieldLabel        matlab.ui.control.Label
        Th6Angle                 matlab.ui.control.NumericEditField
        Tab2                     matlab.ui.container.Tab
        Manual_ModePanel         matlab.ui.container.Panel
        Joint1SliderLabel        matlab.ui.control.Label
        Joint1Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint3Slider             matlab.ui.control.Slider
        Joint4SliderLabel        matlab.ui.control.Label
        Joint4Slider             matlab.ui.control.Slider
        Joint5SliderLabel        matlab.ui.control.Label
        Joint5Slider             matlab.ui.control.Slider
        Joint6SliderLabel        matlab.ui.control.Label
        Joint6Slider             matlab.ui.control.Slider
        joint1_value_dis         matlab.ui.control.NumericEditField
        joint2_value_dis         matlab.ui.control.NumericEditField
        joint3_value_dis         matlab.ui.control.NumericEditField
        joint4_value_dis         matlab.ui.control.NumericEditField
        joint5_value_dis         matlab.ui.control.NumericEditField
        joint6_value_dis         matlab.ui.control.NumericEditField
    end


    properties (Access =  public)
        m1_stepps=0;
        m2_stepps=0;
        m3_stepps=0;
        m4_stepps=0;
        m5_stepps=0;
        m6_stepps=0;
    end

    properties (Access = private)
       
           % define colors
        GREEN_COLOR = [0 1 0];
        RED_COLOR = [1 0 0];
      
    end

    methods (Access = public)
    
        
    end

    methods (Access = private)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   %insure serial not busy
%            delete(instrfind({'Port'},{'COM3'}));
%            global s;
%            s=serial('COM3','BAUD', 9600);
%            fopen(s);
%            pause(2);
%                 app.Lamp.Color = app.GREEN_COLOR; 
            
          % looking for available serial ports 
          % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
            
        end

        % Button pushed function: Inverse_Kinematics
        function Inverse_KinematicsButtonPushed(app, event)
            U(1)= app.XPos.Value;
            U(2)= app.YPos.Value;
            U(3)= app.ZPos.Value;
            U(4)= app.Alpha.Value;
            U(5)= app.Bita.Value;
            U(6)= app.Gama.Value;
           [Y]=inverse_kinematics_v2(U)    ;   
           app.Th1Angle.Value=Y(1);
           app.Th2Angle.Value=Y(2);
           app.Th3Angle.Value=Y(3);
           app.Th4Angle.Value=Y(4);
           app.Th5Angle.Value=Y(5);
           app.Th6Angle.Value=Y(6);
           Y(1)=Y(1)*11.11;
           Y(2)=Y(2)*25;
           Y(3)=Y(3)*25;
           Y(4)=Y(4)*6.67;
           Y(5)=Y(5)*6.67;
           Y(6)=Y(6)*6.67;

          global s;

           for i=1:1:6             
          
           fprintf(s, '%g\n', [Y(i)]);
            end
        
                
        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            
           % delete(instrfind({'Port'},{app.PortList.Value}));%myport
           global s;
           s=serial(app.PortList.Value,'BAUD', 115200);
           fopen(s);
           pause(2);  
              app.Lamp.Color = app.GREEN_COLOR;
        end

        % Button pushed function: diconnectButton
        function diconnectButtonPushed(app, event)
              global s;
            fclose(s);
            clear s; %Remove the variable
            app.Lamp.Color = app.RED_COLOR;
               
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            app.m1_stepps= app.Joint1Slider.Value;
            app.m1_stepps = app.m1_stepps*11.11;
            Y(1)=app.m1_stepps;
            Y(2)=1;
            Y(3)=1;
            Y(4)=1;
            Y(5)=1;
            Y(6)=1;
            app.joint1_value_dis.Value=app.m1_stepps;
            global s;
            for i=1:1:6
                fprintf(s, '%g\n', [Y(i)]);
            end
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            app.m2_stepps= app.Joint2Slider.Value;
            app.m2_stepps =app.m2_stepps*25;
            Y(1)=1;
            Y(2)=app.m2_stepps;
            Y(3)=1;
            Y(4)=1;
            Y(5)=1;
            Y(6)=1;
            app.joint2_value_dis.Value=app.m2_stepps;
            global s;
            for i=1:1:6
                fprintf(s, '%g\n', [Y(i)]);
            end
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            app.m3_stepps= app.Joint3Slider.Value;
            app.m3_stepps=app.m3_stepps*(25);
            Y(1)=1;
            Y(2)=1;
            Y(3)=app.m3_stepps;
            Y(4)=1;
            Y(5)=1;
            Y(6)=1;
            app.joint3_value_dis.Value=app.m3_stepps;
            global s;
            for i=1:1:6
                fprintf(s,'%g\n', [Y(i)]);
            end
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            app.m4_stepps = app.Joint4Slider.Value;
            app.m4_stepps=app.m4_stepps*6.67;
            Y(1)=1;
            Y(2)=1;
            Y(3)=1;
            Y(4)=app.m4_stepps;
            Y(5)=1;
            Y(6)=1;
            app.joint4_value_dis.Value=app.m4_stepps;
            global s;
            for i=1:1:6
                fprintf(s,'%g\n', [Y(i)]);
            end
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            app.m5_stepps = app.Joint5Slider.Value;
            app.m5_stepps=app.m5_stepps*6.67;
            Y(1)=1;
            Y(2)=1;
            Y(3)=1;
            Y(4)=1;
            Y(5)=app.m5_stepps;
            Y(6)=1;
            app.joint5_value_dis.Value=app.m5_stepps;
            global s;
            for i=1:1:6
                fprintf(s,'%g\n', [Y(i)]);
            end
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            app.m6_stepps = app.Joint6Slider.Value;
            app.m6_stepps=app.m6_stepps*6.67;
            Y(1)=1;
            Y(2)=1;
            Y(3)=1;
            Y(4)=1;
            Y(5)=1;
            Y(6)=app.m6_stepps;
            app.joint6_value_dis.Value=app.m6_stepps;
          global s;      
           for i=1:1:6             
           fprintf(s,'%g\n', [Y(i)]);
            end 
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
             % delete all serial port objets from MATLAB memory
            % (Instrument Control Toolbox required)
            delete(instrfind);
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARM_GUI = uifigure;
            app.ARM_GUI.Name = 'ARM_GUI';
            app.TabGroup = uitabgroup(app.ARM_GUI);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab1';
            app.Inverse_KinematicsPanel = uipanel(app.Tab1);
            app.Inverse_KinematicsPanel.Title = 'Inverse_Kinematics';
            app.DOFROBOTICArmLabel = uilabel(app.Inverse_KinematicsPanel);
            app.DOFROBOTICArmLabel.Text = '6DOF  ROBOTIC Arm';
            app.ArduinoPortPanel = uipanel(app.Inverse_KinematicsPanel);
            app.ArduinoPortPanel.Title = 'Arduino Port';
            app.connectButton = uibutton(app.ArduinoPortPanel, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.diconnectButton = uibutton(app.ArduinoPortPanel, 'push');
            app.diconnectButton.ButtonPushedFcn = createCallbackFcn(app, @diconnectButtonPushed, true);
            app.diconnectButton.Text = 'diconnect';
            app.Lamp = uilamp(app.ArduinoPortPanel);
            app.PortDropDownLabel = uilabel(app.ArduinoPortPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortList = uidropdown(app.ArduinoPortPanel);
            app.PortList.Items = {'COM3', 'COM6'};
            app.PortList.Value = 'COM6';
            app.RefreshButton = uibutton(app.ArduinoPortPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.Inverse_Kinematics = uibutton(app.Inverse_KinematicsPanel, 'push');
            app.Inverse_Kinematics.ButtonPushedFcn = createCallbackFcn(app, @Inverse_KinematicsButtonPushed, true);
            app.Inverse_Kinematics.Text = 'Inverse_Kinematics';
            app.XEditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XPos = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.XPos.Value = 321.85;
            app.YEditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YPos = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZPos = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.ZPos.Value = 460.5;
            app.AlphaEditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.Alpha = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.BitaEditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.BitaEditFieldLabel.Text = 'Bita';
            app.Bita = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.GamaEditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.GamaEditFieldLabel.Text = 'Gama';
            app.Gama = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.Gama.Value = 180;
            app.Th1EditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.Th1EditFieldLabel.Text = 'Th1';
            app.Th1Angle = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.Th1Angle.Editable = 'off';
            app.Th2EditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.Th2EditFieldLabel.Text = 'Th2';
            app.Th2Angle = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.Th2Angle.Editable = 'off';
            app.Th3EditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.Th3EditFieldLabel.Text = 'Th3';
            app.Th3Angle = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.Th3Angle.Editable = 'off';
            app.Th4EditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.Th4EditFieldLabel.Text = 'Th4';
            app.Th4Angle = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.Th4Angle.Editable = 'off';
            app.Th5EditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.Th5EditFieldLabel.Text = 'Th5';
            app.Th5Angle = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.Th5Angle.Editable = 'off';
            app.Th6EditFieldLabel = uilabel(app.Inverse_KinematicsPanel);
            app.Th6EditFieldLabel.Text = 'Th6';
            app.Th6Angle = uieditfield(app.Inverse_KinematicsPanel, 'numeric');
            app.Th6Angle.Editable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Manual_ModePanel = uipanel(app.Tab2);
            app.Manual_ModePanel.Title = 'Manual_Mode';
            app.Joint1SliderLabel = uilabel(app.Manual_ModePanel);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.Manual_ModePanel);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.Manual_ModePanel);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.Manual_ModePanel);
            app.Joint2Slider.Limits = [-108 45];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.Value = 45;
            app.Joint3SliderLabel = uilabel(app.Manual_ModePanel);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.Manual_ModePanel);
            app.Joint3Slider.Limits = [-15 194];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.Value = -15;
            app.Joint4SliderLabel = uilabel(app.Manual_ModePanel);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.Manual_ModePanel);
            app.Joint4Slider.Limits = [-180 180];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.Manual_ModePanel);
            app.Joint5SliderLabel.Text = 'Joint5';
            app.Joint5Slider = uislider(app.Manual_ModePanel);
            app.Joint5Slider.Limits = [-15 148];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.Value = -15;
            app.Joint6SliderLabel = uilabel(app.Manual_ModePanel);
            app.Joint6SliderLabel.Text = 'Joint6';
            app.Joint6Slider = uislider(app.Manual_ModePanel);
            app.Joint6Slider.Limits = [-180 180];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.joint1_value_dis = uieditfield(app.Manual_ModePanel, 'numeric');
            app.joint2_value_dis = uieditfield(app.Manual_ModePanel, 'numeric');
            app.joint3_value_dis = uieditfield(app.Manual_ModePanel, 'numeric');
            app.joint4_value_dis = uieditfield(app.Manual_ModePanel, 'numeric');
            app.joint5_value_dis = uieditfield(app.Manual_ModePanel, 'numeric');
            app.joint6_value_dis = uieditfield(app.Manual_ModePanel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_V1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARM_GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARM_GUI)
        end
    end
end